classdef GUIapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LayersNeuronsEditFieldLabel     matlab.ui.control.Label
        LayersNeuronsEditField          matlab.ui.control.EditField
        trainratioEditFieldLabel        matlab.ui.control.Label
        trainratioEditField             matlab.ui.control.EditField
        validationratioEditFieldLabel   matlab.ui.control.Label
        validationratioEditField        matlab.ui.control.EditField
        testratioEditFieldLabel         matlab.ui.control.Label
        testratioEditField              matlab.ui.control.EditField
        LoadDatasetButton               matlab.ui.control.Button
        TrainButton                     matlab.ui.control.Button
        LoadNetworkButton               matlab.ui.control.Button
        SaveNetworkButton               matlab.ui.control.Button
        NeuralNetworkLabel              matlab.ui.control.Label
        ResultsEditFieldLabel           matlab.ui.control.Label
        ResultsEditField                matlab.ui.control.EditField
        TrainFunctionDropDownLabel      matlab.ui.control.Label
        TrainFunctionDropDown           matlab.ui.control.DropDown
        OutputTransferFcnDropDown_2Label  matlab.ui.control.Label
        OutputTransferFcnDropDown_2     matlab.ui.control.DropDown
        ClassifyImageButton             matlab.ui.control.Button
        maxepochsEditFieldLabel         matlab.ui.control.Label
        maxepochsEditField              matlab.ui.control.EditField
        HiddenTransferFcnDropDownLabel  matlab.ui.control.Label
        HiddenTransferFcnDropDown       matlab.ui.control.DropDown
        DivedeFcnDropDownLabel          matlab.ui.control.Label
        DivedeFcnDropDown               matlab.ui.control.DropDown
        Button                          matlab.ui.control.Button
        SimulateButton                  matlab.ui.control.Button
        CreateNetworkButton             matlab.ui.control.Button
        DatasetEditFieldLabel           matlab.ui.control.Label
        DatasetEditField                matlab.ui.control.EditField
        ReverseDatasetCheckBox          matlab.ui.control.CheckBox
    end

    properties (Access = private)
        net;
        imageInputs;
        imageTargets;
        imgsResolution = 12;
        hasDataset = false;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            CreateNetworkButtonPushed(app)
            app.SaveNetworkButton.Enable = false; % so podemos guardar depois de ter treinado
            app.SimulateButton.Enable = false;
            app.ClassifyImageButton.Enable = false;
        end

        % Button pushed function: CreateNetworkButton
        function CreateNetworkButtonPushed(app, event)
            layers = str2num(app.LayersNeuronsEditField.Value);
            app.net = feedforwardnet(layers);
            
            app.net.trainFcn = app.TrainFunctionDropDown.Value;
            app.net.layers{size(layers,2) + 1}.transferFcn = app.OutputTransferFcnDropDown_2.Value;
            for i=1 : size(layers,2)
                app.net.layers{i}.transferFcn = app.HiddenTransferFcnDropDown.Value;
            end
            
            app.net.divideFcn = app.DivedeFcnDropDown.Value;
            app.net.divideParam.trainRatio = str2double(app.trainratioEditField.Value);
            app.net.divideParam.valRatio = str2double(app.validationratioEditField.Value);
            app.net.divideParam.testRatio = str2double(app.testratioEditField.Value);
            app.net.trainParam.epochs = str2num(app.maxepochsEditField.Value);
            
            app.SaveNetworkButton.Enable = false;
            app.SimulateButton.Enable = false;
            app.ClassifyImageButton.Enable = false;
        end

        % Button pushed function: ClassifyImageButton
        function ClassifyImageButtonPushed(app, event)
            if ~app.hasDataset
                app.ResultsEditField.Value = 'Não tem dataset';
               return
            end
            [file,path]= uigetfile('*.jpg');
            if ~path
                return
            end
            filePath = [path file];
            app.DatasetEditField.Value = filePath;
            
            imgResolution = 12;
            imageInputs1 = zeros(imgResolution * imgResolution , 1);
            image = imread(filePath);
            if size(image,3) > 1
                image = rgb2gray(image);
            end
            image = imresize(image, [imgResolution imgResolution]);
            image = double(image)/255;
            imageInputs1(:,1) = image(:);
            
            imageOutputs = sim(app.net, imageInputs1);
            
            [~, obtained] = max(imageOutputs(:, 1));
            
            app.ResultsEditField.Value = strcat('A rede diz que é o caracter -> ', num2str(obtained));
        end

        % Button pushed function: LoadDatasetButton
        function LoadDatasetButtonPushed(app, event)
            datasetPath = uigetdir;
            if ~datasetPath
                return
            end
            reverse = 0;
            if(app.ReverseDatasetCheckBox.Value)
                reverse = 1;
            end
            [app.imageInputs,app.imageTargets] = readyImages(datasetPath, app.imgsResolution, '%d','jpg',reverse,1);
            app.DatasetEditField.Value = datasetPath;
            app.hasDataset = true;
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
           if ~app.hasDataset
                app.ResultsEditField.Value = 'Não tem dataset';
               return
           end
            output = sim(app.net, app.imageInputs);
            accuracy = testNetworkAccuracy(output,app.imageTargets);
            app.ResultsEditField.Value = strcat('Precisao total -> ', num2str(accuracy));
            plotconfusion(app.imageTargets, output)
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            if ~app.hasDataset
                app.ResultsEditField.Value = 'Não tem dataset';
               return
           end
            [app.net, trainResult] = train(app.net, app.imageInputs, app.imageTargets);
            app.ResultsEditField.Value = trainResult.stop;
            
            app.SaveNetworkButton.Enable = true;
            app.SimulateButton.Enable = true;
            app.ClassifyImageButton.Enable = true;
        end

        % Button pushed function: SaveNetworkButton
        function SaveNetworkButtonPushed(app, event)
            path = uigetdir;
            if ~path
                return
            end
            load(path,'net')
            app.net = net;
        end

        % Button pushed function: LoadNetworkButton
        function LoadNetworkButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            if ~path
                return
            end
            load([path file],'net')
            app.net = net;
            
            app.ClassifyImageButton.Enable = true;
            app.SimulateButton.Enable = true;
            app.SaveNetworkButton.Enable = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LayersNeuronsEditFieldLabel = uilabel(app.UIFigure);
            app.LayersNeuronsEditFieldLabel.Text = 'Layers Neurons';
            app.LayersNeuronsEditField = uieditfield(app.UIFigure, 'text');
            app.LayersNeuronsEditField.Value = '20 10';
            app.trainratioEditFieldLabel = uilabel(app.UIFigure);
            app.trainratioEditFieldLabel.Text = 'train ratio';
            app.trainratioEditField = uieditfield(app.UIFigure, 'text');
            app.trainratioEditField.Value = '0.7';
            app.validationratioEditFieldLabel = uilabel(app.UIFigure);
            app.validationratioEditFieldLabel.Text = 'validation ratio';
            app.validationratioEditField = uieditfield(app.UIFigure, 'text');
            app.validationratioEditField.Value = '0.15';
            app.testratioEditFieldLabel = uilabel(app.UIFigure);
            app.testratioEditFieldLabel.Text = 'test ratio';
            app.testratioEditField = uieditfield(app.UIFigure, 'text');
            app.testratioEditField.Value = '0.15';
            app.LoadDatasetButton = uibutton(app.UIFigure, 'push');
            app.LoadDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatasetButtonPushed, true);
            app.LoadDatasetButton.Text = 'Load Dataset';
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.LoadNetworkButton = uibutton(app.UIFigure, 'push');
            app.LoadNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNetworkButtonPushed, true);
            app.LoadNetworkButton.Text = 'Load Network';
            app.SaveNetworkButton = uibutton(app.UIFigure, 'push');
            app.SaveNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNetworkButtonPushed, true);
            app.SaveNetworkButton.Text = 'Save Network';
            app.NeuralNetworkLabel = uilabel(app.UIFigure);
            app.NeuralNetworkLabel.Text = 'Neural Network ';
            app.ResultsEditFieldLabel = uilabel(app.UIFigure);
            app.ResultsEditFieldLabel.Text = 'Results';
            app.ResultsEditField = uieditfield(app.UIFigure, 'text');
            app.ResultsEditField.Editable = 'off';
            app.TrainFunctionDropDownLabel = uilabel(app.UIFigure);
            app.TrainFunctionDropDownLabel.Text = 'Train Function';
            app.TrainFunctionDropDown = uidropdown(app.UIFigure);
            app.TrainFunctionDropDown.Items = {'trainlm', 'trainrp', 'traincgp'};
            app.TrainFunctionDropDown.Value = 'trainlm';
            app.OutputTransferFcnDropDown_2Label = uilabel(app.UIFigure);
            app.OutputTransferFcnDropDown_2Label.Text = 'Output Transfer Fcn';
            app.OutputTransferFcnDropDown_2 = uidropdown(app.UIFigure);
            app.OutputTransferFcnDropDown_2.Items = {'purelin', 'logsig', 'tansig'};
            app.OutputTransferFcnDropDown_2.Value = 'purelin';
            app.ClassifyImageButton = uibutton(app.UIFigure, 'push');
            app.ClassifyImageButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyImageButtonPushed, true);
            app.ClassifyImageButton.Text = 'Classify Image';
            app.maxepochsEditFieldLabel = uilabel(app.UIFigure);
            app.maxepochsEditFieldLabel.Text = 'max epochs';
            app.maxepochsEditField = uieditfield(app.UIFigure, 'text');
            app.maxepochsEditField.Value = '200';
            app.HiddenTransferFcnDropDownLabel = uilabel(app.UIFigure);
            app.HiddenTransferFcnDropDownLabel.Text = 'Hidden Transfer Fcn';
            app.HiddenTransferFcnDropDown = uidropdown(app.UIFigure);
            app.HiddenTransferFcnDropDown.Items = {'logsig', 'purelin', 'tansig'};
            app.HiddenTransferFcnDropDown.Value = 'logsig';
            app.DivedeFcnDropDownLabel = uilabel(app.UIFigure);
            app.DivedeFcnDropDownLabel.Text = 'Divede Fcn';
            app.DivedeFcnDropDown = uidropdown(app.UIFigure);
            app.DivedeFcnDropDown.Items = {'divideint', 'dividerand'};
            app.DivedeFcnDropDown.Value = 'divideint';
            app.Button = uibutton(app.UIFigure, 'push');
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.CreateNetworkButton = uibutton(app.UIFigure, 'push');
            app.CreateNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNetworkButtonPushed, true);
            app.CreateNetworkButton.Text = 'Create Network';
            app.DatasetEditFieldLabel = uilabel(app.UIFigure);
            app.DatasetEditFieldLabel.Text = 'Dataset:';
            app.DatasetEditField = uieditfield(app.UIFigure, 'text');
            app.DatasetEditField.Editable = 'off';
            app.ReverseDatasetCheckBox = uicheckbox(app.UIFigure);
            app.ReverseDatasetCheckBox.Text = 'Reverse Dataset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end